classdef getCond < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        specifyCond  matlab.ui.Figure
        confirm      matlab.ui.control.Button
        text2        matlab.ui.control.Label
        table        matlab.ui.control.Table
    end

    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.table.BackgroundColor = [1 1 1];
            app.table.ColumnFormat = {'numeric' {'1' '0'} 'char' 'numeric'};
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function specifyCond_OpeningFcn(app, varargin)
            % --- Executes just before specifyCond is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.specifyCond, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to specifyCond (see VARARGIN)
            
            % Choose default command line output for specifyCond
            handles.output = hObject;
            handles.simTag = datestr(now,30);
            if(nargin > 3)
                for index = 1:2:(nargin-3)
                    if nargin-3==index, break, end
                    switch lower(varargin{index})
                        case 'numtissues'; handles.tId = varargin{index+1};
                        case 'simtag'; handles.simTag = varargin{index+1};
                    end
                end
                tbl = cell(handles.tId,4);
                tbl(:,1) = mat2cell(handles.tId,ones(andles.tId,1));
                tbl(:,2) = mat2cell(zeros(handles.tId,1),ones(handles.tId,1));
                tbl(:,4) = mat2cell(zeros(handles.tId,1),ones(handles.tId,1));
            else
                def = {'white', 0.126;
                'gray', 0.276;
                'csf', 1.65;
                'bone', 0.01;
                'skin', 0.465;
                'air', 2.5e-14};
                handles.tId = 6;
                tbl = cell(6,4);
                tbl(:,1) = mat2cell((1:6)',ones(6,1));
                tbl(:,2) = mat2cell([1 1 0 0 0 0]',ones(6,1));
                tbl(1:6,3:4) = def;
            end
            if exist(fullfile(fileparts(mfilename('fullpath')),'conductivities',['cond_' handles.simTag '.mat']),'file')
                c = load(fullfile(fileparts(mfilename('fullpath')),['cond_' handles.simTag '.mat']));
                if length(handles.tId) == length(c.cond)
                    tbl(:,1:4) = c.cond(:,1:4);
                else
                    tbl(1:6,3:4) = def;
                end
            end
            set(handles.table,'Data',tbl);
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: confirm
        function confirm_Callback(app, event)
            % --- Executes on button press in confirm.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to confirm (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            tbl = get(handles.table,'Data'); % get data

            % Check Proper Input
            if sum(cellfun(@isempty,tbl(:,1))) > 0
                disp(['Please Specify Name for ALL ' length(handles.tId) ' Tissues']); 
                return
            end
            if sum(cellfun(@isempty,tbl(:,2))) > 0
                disp(['Please Specify Conductivity for ALL ' length(handles.tId) ' Tissues']); 
                return
            end
            save(fullfile(fileparts(mfilename('fullpath')),['cond_' handles.simTag '.mat']),'cond'); close(gcf)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.specifyCond = uifigure('Visible', 'off');
            app.specifyCond.Name = 'Conductivity Values';
            app.specifyCond.Tag = 'specifyCond';
            app.table = uitable(app.specifyCond);
            app.table.ColumnName = {'Mask Index'; 'Brain'; 'Name'; 'Conductivity (S/m)'};
            app.table.ColumnEditable = [false true true true];
            app.table.Tag = 'table';
            app.text2 = uilabel(app.specifyCond);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Specify Conductivity Values for ROAST';
            app.confirm = uibutton(app.specifyCond, 'push');
            app.confirm.ButtonPushedFcn = createCallbackFcn(app, @confirm_Callback, true);
            app.confirm.Tag = 'confirm';
            app.confirm.Text = 'Confirm';
            % Show the figure after all components are created
            app.specifyCond.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = getCond(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.specifyCond)

                % Execute the startup function
                runStartupFcn(app, @(app)specifyCond_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.specifyCond)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.specifyCond)
        end
    end
end