classdef roaster < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AppFig           matlab.ui.Figure
        FileMenu         matlab.ui.container.Menu
        NewProjectMenu   matlab.ui.container.Menu
        OpenProjectMenu  matlab.ui.container.Menu
        SaveProjectMenu  matlab.ui.container.Menu
        HelpMenu         matlab.ui.container.Menu
        ContactUsMenu    matlab.ui.container.Menu
        LogBox           matlab.ui.control.TextArea
        RunROASTButton   matlab.ui.control.Button
        SettingsTable    matlab.ui.control.Table
        NumData          matlab.ui.control.EditField
        NLabel           matlab.ui.control.Label
        DataTree         matlab.ui.container.Tree
        AnatomyDataNode  matlab.ui.container.TreeNode
        Plotter          matlab.ui.control.UIAxes
        AnatomyMenu      matlab.ui.container.ContextMenu
        AddMenu          matlab.ui.container.Menu
        AddSel           matlab.ui.container.Menu
        AddAll           matlab.ui.container.Menu
        RemoveMenu       matlab.ui.container.Menu
        RmSel            matlab.ui.container.Menu
        RmAll            matlab.ui.container.Menu
        PlotMenu         matlab.ui.container.Menu
    end

    % Global Variables
    properties (Access = public)
        nodes = struct(); % Data Tree Nodes
        N = []; % N of Data
        rootDir = fileparts(fileparts(fileparts(mfilename("fullpath")))); % Path to App
        data = struct(); % Data Structure
        cond = table(); % Conductivity Values
        projFile = []; % Abs Path to ProjectFile
        projTag = []; % Project Name
        condStatus = 0;
    end

    % Helper Functions
    methods (Access = private)

        % Logging Function
        function log2box(app,msg)

            % New Log
            new = sprintf('[%s] %s', ...
                char(datetime('now','TimeZone','local','Format', ...
                'd-MMM-y HH:mm:ss Z')),msg); 
            
            % Limit the maximum length of the TextArea content
            maxLength = 1000;
            if length(new) > maxLength
                new = new(end - maxLength + 1 : end);
            end
    
            % Log with Carriage Return
            if isempty(app.LogBox.Value{end})
                app.LogBox.Value{end} = new; 
            else
                app.LogBox.Value{end+1} = new; 
            end
        end

         % Remove Data Function
        function dataRm(app,node)
            tree = app.(node).Children;
            tree.delete;
        end

        % Add Data Function
        function dataAdd(app,nodeId)
            
            % Top Node
            app.nodes.(['Node' num2str(nodeId)]) = uitreenode(app.AnatomyDataNode, ...
                'Text',['S' num2str(nodeId,'%04d')]);

            % Folder Icon
            iconPath = fullfile(app.rootDir,'lib','Resources','unknownicon.gif');

            % Create T1Node
            app.nodes.(['T1Node' num2str(nodeId)]) = uitreenode(app.nodes.(['Node',...
                num2str(nodeId)]),'Text','T1','Icon',iconPath);

            % Create T2Node
            app.nodes.(['T2Node' num2str(nodeId)]) = uitreenode(app.nodes.(['Node',...
                num2str(nodeId)]),'Text','T2','Icon',iconPath);

            % Create SegNode
            app.nodes.(['SegNode' num2str(nodeId)]) = uitreenode(app.nodes.(['Node',...
                num2str(nodeId)]),'Text','Segmentation','Icon',iconPath);
            
%             % Red Text
%             addStyle(app.DataTree,uistyle('FontColor','r'),'node', ...
%                 [ ...
%                 app.nodes.(['T1Node' num2str(nodeId)]) ...
%                 app.nodes.(['T2Node' num2str(nodeId)]) ...
%                 app.nodes.(['SegNode' num2str(nodeId)]) ...
%                 ])
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            log2box(app,'Welcome to ROASTer GUI v1.0 !')

            % Load Data
            load(fullfile(app.rootDir,'lib','Resources', ...
                'exampleMesh.mat'),'face','node')
            fid = fopen(fullfile(app.rootDir, ...
                'lib','Resources','exampleEF.pos'));
            fgetl(fid); C = textscan(fid,'%d %f %f %f'); 
            fclose(fid);

            % Update Settings Table
            app.data.Settings = cell2table({
                'F3','Pad',-2,'70 50 3','lr'; ...
                'F4','Pad',2,'70 50 3','lr'}, ...
                'VariableNames', {'ElecLoc', 'ElecType', ...
                'Intensity (mA)', 'ElecSize [l w h] (mm)', ...
                'ElecOri'});
            app.SettingsTable.Data = app.data.Settings;

            % Process Data
            color = nan(size(node,1),1);
            color(C{1}) = sqrt(C{2}.^2+C{3}.^2+C{4}.^2);
            dataShow = [node(:,1:3) color];
            indFace = face(face(:,4) == 2,1:4);
            
            % Plot Data
            plotsurf(dataShow,indFace,'Parent',app.Plotter); 
            shading(app.Plotter,'interp'); 
            clim(app.Plotter,[0 0.4]); 
            colormap(app.Plotter,'turbo'); 
            title(app.Plotter,'Example FEM: F3-F4 @ 2mA')
            axis(app.Plotter,'off'); view(app.Plotter,170,35)
            hc1 = colorbar(app.Plotter); 
            set(hc1,'FontSize',12,'YAxisLocation','right');
            title(hc1,'V/m','FontSize',16);
            zoom(app.Plotter,1.1)
        end

        % Value changed function: NumData
        function NumDataValueChanged(app, event)
            
            % Check for Project File
            if isempty(app.projFile)
                log2box(app,'Please Create A Project File ...')
                return;
            end

            % Convert to Number
            try
                app.N = str2double(app.NumData.Value);
            catch
                log2box(app,'Please enter a valid number'); 
                return;
            end

            % Check if the input is a positive integer
            if isnan(app.N) || app.N < 1 || mod(app.N, 1) ~= 0
                log2box(app,'Please enter a valid number');
                return;
            end

            % Create Nodes
            dataRm(app,'AnatomyDataNode') % Start Fresh
            for i = 1 : app.N; dataAdd(app,i); end % Add Data
            log2box(app,[num2str(app.N) ' Data Node(s) Created !'])
        end

        % Menu selected function: AddSel
        function AddSelMenuSelected(app, event)
            % Check for Project File
            if isempty(app.projFile)
                log2box(app,'Please Create A Project File ...')
                return;
            end

            % Get Node Info
            selectedNode = app.DataTree.SelectedNodes;
            nodeId = sscanf(selectedNode.Parent.Text,'S%f');
            selIm = selectedNode.Text;

            % Get Filename
            if isfield(app.data,'T1')
                [filename,dirname] = uigetfile('*.nii', ...
                    ['Import ' selIm], ...
                    fileparts(app.data(1).T1));
            elseif isfield(app.data,'T2')
                [filename,dirname] = uigetfile('*.nii', ...
                    ['Import ' selIm], ...
                    fileparts(app.data(1).T2)); 
            elseif isfield(app.data,'Segmentation')
                [filename,dirname] = uigetfile('*.nii', ...
                    ['Import ' selIm], ...
                    fileparts(app.data(1).Segmentation));
            else
                [filename,dirname] = uigetfile('*.nii', ...
                    ['Import ' selIm],app.rootDir); 
            end
            if filename == 0; return; end % No Files Selected :(

            % Add selected File to Data
            if ~isfield(app.data,selIm); app.data.(selIm) = struct(); end
            app.data(nodeId).(selIm) = fullfile(dirname,filename);
             
            % Switch Icon
            iconPath = fullfile(app.rootDir,'lib', ...
                'Resources','HDF_object02.gif');
            selectedNode.Icon = iconPath;             
            log2box(app,['Imported ' selIm ' for S' num2str(nodeId,'%04.f') ' !'])
        end

        % Button pushed function: RunROASTButton
        function RunROASTButtonPushed(app, event)
            % Check for Project File
            if isempty(app.projFile)
                log2box(app,'Please Create A Project File ...')
                return;
            end
            
            % Check if Data Imported 
            if ~isfield(app.data,'T1')
                log2box(app,'Please import at least one T1 ...'); 
                return; 
            end
            
            % Data Directory
            dataDir = fullfile(fileparts(app.projFile),app.projTag);
            if ~exist(dataDir,'dir'); mkdir(dataDir); end

            % Copy T1
            for i = 1:length(app.data)
                if ~exist(fullfile(dataDir,['S' num2str(i,'%04.f')]),'dir')
                    mkdir(fullfile(dataDir,['S' num2str(i,'%04.f')]))
                    log2box(app,['Data Directory Created: ' fullfile(dataDir,['S' num2str(i,'%04.f')])])
                end
                copyfile(app.data(i).T1, ...
                    fullfile(dataDir,['S' num2str(i,'%04.f')],'T1.nii'),'f')
            end

            % Copy T2
            if isfield(app.data,'T2')
                for i = 1:length(app.data)
                    if ~exist(fullfile(dataDir,['S' num2str(i,'%04.f')]),'dir')
                        mkdir(fullfile(dataDir,['S' num2str(i,'%04.f')]))
                    end
                    copyfile(app.data(i).T2, ...
                        fullfile(dataDir,['S' num2str(i,'%04.f')],'T2.nii'),'f')
                end
            end

            % Default Conductivity of ROAST
            conduc = struct('white',0.126,'gray',0.276,'csf',1.65,'bone',0.01,...
                'skin',0.465,'air',2.5e-14,'gel',0.3,'electrode',5.9e7); % literature values

            % Copy Segmentation
            if isfield(app.data,'Segmentation')

                % Make Project Cond Dir
                condDir = fullfile(fileparts(app.projFile), ...
                    'conductivites');
                if ~exist(condDir,'dir'); mkdir(condDir); end

                % Get Tissue Conductivity
                condFile = fullfile(condDir,[app.projTag '_cond.mat']);
                if ~exist(condFile,'file')
                    nii = niftiread(app.data(1).Segmentation);
                    fnii = nii(:); clear nii;
                    tisId = sort(unique(fnii(fnii ~= 0 & ~isnan(fnii))));
                    setCond(app,tisId); % setCond UI
                    waitfor(app,'condStatus',1); % uiwait

                    % Convert to Struct for ROAST
                    conduc = table2struct(array2table(app.cond{:,4}','VariableNames',app.cond{:,2})); % convert to struct for ROAST
                    conduc.gel = 1; conduc.electrode = 2.5e7;  % HARDCODED                                                       % HARDCODED ELEC CONDUCTIVITY
                    conduc.index = app.cond{:,1}; % Get Unique Tissue Indexes
                    conduc.brain = app.cond{:,3}; % Boolean Index of Brain vs Non-brain
                    save(condFile,'conduc'); % Save
                else
                    load(condFile,'conduc'); % Load
                    log2box(app,'Conductivities Loaded !')
                end

                for i = 1:length(app.data)
                    if ~exist(fullfile(dataDir,['S' num2str(i,'%04.f')]),'dir')
                        mkdir(fullfile(dataDir,['S' num2str(i,'%04.f')]))
                    end
                    if exist(fullfile(dataDir,['S' num2str(i,'%04.f')],'T2.nii'),'file')
                        copyfile(app.data(i).Segmentation, ...
                            fullfile(dataDir,['S' num2str(i,'%04.f')],'T1_T1andT2_masks.nii'),'f');
                    else
                        copyfile(app.data(i).Segmentation, ...
                            fullfile(dataDir,['S' num2str(i,'%04.f')],'T1_T1orT2_masks.nii'),'f');
                    end
                end
            end

            % Repeat Gel Conductivity for each electrode
            if length(conduc.gel(:))==1
                conduc.gel = repmat(conduc.gel, ...
                    1,length(app.data.Settings{:,1}));
            end

            % Repeat Electrode Conductivity for each Electrode
            if length(conduc.electrode(:))==1
                conduc.electrode = repmat(conduc.electrode, ...
                    1,length(app.data.Settings{:,1}));
            end

            % Run ROAST
            Nelec = length(app.data.Settings{:,1});
            log2box(app, ...
                sprintf('ROASTing %.0f electrodes @ %.1fmA ...', ...
                Nelec, ...
                sum(abs(app.data.Settings{:,3}))));
            datafdr = dir(fullfile(dataDir,'S*'));

            % Create ROAST Recipe
            tmp = table2cell(app.data.Settings(:,[1 3]))';
            recipe = cell(1,Nelec*2); 
            for i = 1:numel(tmp); recipe{i} = tmp{i}; end; clear tmp;

            % Format ROAST Settings
            elecType = cell(1,Nelec);
            elecSize = cell(1,Nelec);
            elecOri = cell(1,Nelec);
            for i = 1 : Nelec
                elecType{i} = app.data.Settings{i,2}{1};
                elecSize{i} = str2double(strsplit(app.data.Settings{i,4}{1},' '));
                elecOri{i} = app.data.Settings{i,5}{1};
            end

            % ROAST Loop
            for i = 1:length(datafdr)

                % Check for T2
                T2 = fullfile(dataDir,datafdr(i).name,'T2.nii');
                if ~exist(T2,'file'); T2 = []; end

                roast(i,fullfile(dataDir,datafdr(i).name,'T1.nii'), ...
                    recipe, 'elecType', elecType, ...
                    'elecSize', elecSize, 'elecOri', elecOri, ...
                    'T2', T2, ...
                    'conductivities',conduc,'simulationTag',app.projTag)
                log2box(app,['ROAST for ' datafdr(i).name ' Complete !'])
            end
        end

        % Menu selected function: NewProjectMenu
        function NewProjectMenuSelected(app, event)
            [filename,dirname] = uiputfile({'*.mat','ROAST Project (*.mat)'},'Save Project As','roast-project.mat');
            if filename == 0; return; end
            app.projFile = fullfile(dirname,filename);
            [~,app.projTag] = fileparts(filename);
            N = app.N; % N of Data
            rootDir = app.rootDir; % Path to App
            data = app.data; % Data Structure
            cond = app.cond; % Conductivity Values
            projFile = app.projFile; % Abs Path to ProjectFile
            projTag = app.projTag; % Project Name
            save(app.projFile,'N','rootDir','data','cond');
            log2box(app,['''' app.projTag ''' Created !'])
        end

        % Menu selected function: OpenProjectMenu
        function OpenProjectMenuSelected(app, event)
            [filename,dirname] = uigetfile({'*.mat','ROAST Project (*.mat)'},'Load ROAST Project');
            if filename == 0; return; end
            app.projFile = fullfile(dirname,filename);
            [~,app.projTag] = fileparts(filename);
            load(app.projFile,'N','rootDir','data','cond')
            
            % Set N
            app.NumData.Value = num2str(N);

            % Create Nodes
            dataRm(app,'AnatomyDataNode') % Start Fresh
            for i = 1 : app.N; dataAdd(app,i); end % Add Data

            % Update App
            app.rootDir = rootDir;
            app.cond = cond;
            
            % Folder Icon
            iconPath = fullfile(app.rootDir,'lib', ...
                'Resources','HDF_object02.gif');
            
            % Update UI Icons
            app.data = data;
            for i = 1:length(app.data)
                if isfield(app.data,'T1')
                    app.nodes.(['T1Node' num2str(i)]).Icon = iconPath;
                end
                if isfield(app.data,'T2')
                    app.nodes.(['T2Node' num2str(i)]).Icon = iconPath;
                end
                if isfield(app.data,'Segmentation')
                    app.nodes.(['SegNode' num2str(i)]).Icon = iconPath;
                end
            end

            % Update Settings Table
            app.SettingsTable.Data = app.data.Settings;
            log2box(app,['''' app.projTag ''' Loaded !'])
        end

        % Menu selected function: SaveProjectMenu
        function SaveProjectMenuSelected(app, event)
            % Check for Project File
            if isempty(app.projFile)
                log2box(app,'Please Create A Project File ...')
                return;
            end

            % Get Current Data
            N = app.N; % N of Data
            rootDir = app.rootDir; % Path to App
            data = app.data; % Data Structure
            cond = app.cond; % Conductivity Values
            projFile = app.projFile; % Abs Path to ProjectFile
            projTag = app.projTag; % Project Name
            save(app.projFile,'N','rootDir','data','cond')
            log2box(app,['''' app.projTag ''' Saved !'])
        end

        % Cell edit callback: SettingsTable
        function SettingsTableCellEdit(app, event)
            newData = event.NewData;
            app.data.Settings = newData;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AppFig = uifigure('Visible', 'off');
            app.AppFig.Name = 'ROASTER GUI';
            app.AppFig.Icon = fullfile(pathToMLAPP, 'jbrain_icon.png');
            app.FileMenu = uimenu(app.AppFig);
            app.FileMenu.Text = 'File';
            app.NewProjectMenu = uimenu(app.FileMenu);
            app.NewProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @NewProjectMenuSelected, true);
            app.NewProjectMenu.Text = 'New Project';
            app.OpenProjectMenu = uimenu(app.FileMenu);
            app.OpenProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenProjectMenuSelected, true);
            app.OpenProjectMenu.Text = 'Open Project';
            app.SaveProjectMenu = uimenu(app.FileMenu);
            app.SaveProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectMenuSelected, true);
            app.SaveProjectMenu.Text = 'Save Project';
            app.HelpMenu = uimenu(app.AppFig);
            app.HelpMenu.Text = 'Help';
            app.ContactUsMenu = uimenu(app.HelpMenu);
            app.ContactUsMenu.Text = 'Contact Us';
            app.Plotter = uiaxes(app.AppFig);
            xlabel(app.Plotter, 'X')
            ylabel(app.Plotter, 'Y')
            zlabel(app.Plotter, 'Z')
            app.DataTree = uitree(app.AppFig);
            app.AnatomyDataNode = uitreenode(app.DataTree);
            app.AnatomyDataNode.Icon = fullfile(pathToMLAPP, 'foldericon.gif');
            app.AnatomyDataNode.Text = 'Anatomy Data';
            app.NLabel = uilabel(app.AppFig);
            app.NLabel.Text = 'N =';
            app.NumData = uieditfield(app.AppFig, 'text');
            app.NumData.ValueChangedFcn = createCallbackFcn(app, @NumDataValueChanged, true);
            app.SettingsTable = uitable(app.AppFig);
            app.SettingsTable.ColumnName = {'ElecLoc'; 'ElecType'; 'Intensity (mA)'; 'ElecSize [l w h] (mm)'; 'ElecOri'};
            app.SettingsTable.RowName = {};
            app.SettingsTable.ColumnEditable = true;
            app.SettingsTable.CellEditCallback = createCallbackFcn(app, @SettingsTableCellEdit, true);
            app.RunROASTButton = uibutton(app.AppFig, 'push');
            app.RunROASTButton.ButtonPushedFcn = createCallbackFcn(app, @RunROASTButtonPushed, true);
            app.RunROASTButton.Icon = fullfile(pathToMLAPP, 'greenarrowicon.gif');
            app.RunROASTButton.Text = 'Run ROAST';
            app.LogBox = uitextarea(app.AppFig);
            app.LogBox.Editable = 'off';
            app.AnatomyMenu = uicontextmenu(app.AppFig);
            app.AddMenu = uimenu(app.AnatomyMenu);
            app.AddMenu.Text = 'Add';
            app.AddSel = uimenu(app.AddMenu);
            app.AddSel.MenuSelectedFcn = createCallbackFcn(app, @AddSelMenuSelected, true);
            app.AddSel.Text = 'Selected';
            app.AddAll = uimenu(app.AddMenu);
            app.AddAll.Text = 'All';
            app.RemoveMenu = uimenu(app.AnatomyMenu);
            app.RemoveMenu.Text = 'Remove';
            app.RmSel = uimenu(app.RemoveMenu);
            app.RmSel.Text = 'Selected';
            app.RmAll = uimenu(app.RemoveMenu);
            app.RmAll.Text = 'All';
            app.PlotMenu = uimenu(app.AnatomyMenu);
            app.PlotMenu.Text = 'Plot';
            % Assign app.AnatomyMenu
            app.DataTree.ContextMenu = app.AnatomyMenu;
            app.AnatomyDataNode.ContextMenu = app.AnatomyMenu;
            % Show the figure after all components are created
            app.AppFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = roaster

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AppFig)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.AppFig)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AppFig)
        end
    end
end